classdef gui_results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SelectResultsTab              matlab.ui.container.Tab
        AvailableResultsListBoxLabel  matlab.ui.control.Label
        AvailableResultsListBox       matlab.ui.control.ListBox
        AddResultButton               matlab.ui.control.Button
        SelectedResultsListBoxLabel   matlab.ui.control.Label
        SelectedResultsListBox        matlab.ui.control.ListBox
        RefreshButton                 matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        MountForcesButton             matlab.ui.control.Button
        CGDisplacementsButton         matlab.ui.control.Button
        StaticDeflectionsButton       matlab.ui.control.Button
        StaticTestsButton             matlab.ui.control.Button
        MountLocationsTab             matlab.ui.container.Tab
        MountLocationsButton          matlab.ui.control.Button
        MountStiffnessTab             matlab.ui.container.Tab
        MountStiffnessButton          matlab.ui.control.Button
        KEDTab                        matlab.ui.container.Tab
        KEDButton                     matlab.ui.control.Button
        FrequencyTab                  matlab.ui.container.Tab
        FrequencyButton               matlab.ui.control.Button
        StaticDeflectionsTab          matlab.ui.container.Tab
        MountForcesTab                matlab.ui.container.Tab
        CGDisplacementsTab            matlab.ui.container.Tab
        ResultsLabel                  matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
    end


    properties (Access = private)
         % Description
         
         
    end

    properties (Access = public)
        n=0;%number of selected results
        items % cell array of selected items
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            RefreshButtonPushed(app);


        end

        % Button pushed function: AddResultButton
        function AddResultButtonPushed(app, event)
            app.n=app.n+1;
            app.SelectedResultsListBox.Items(app.n)= {app.AvailableResultsListBox.Value};
            app.items = app.SelectedResultsListBox.Items;
        end

        % Value changed function: AvailableResultsListBox
        function AvailableResultsListBoxValueChanged(app, event)


        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.SelectedResultsListBox.Items={''}
            
           app.n=0
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            
            
            k=size(app.items);
            
            for i=1:k(2)
              
               load(['SavedResults/',app.items{i}]);
               
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               
               
               gui_plot_freq(h,name);
                
            end
            
        end

        % Button pushed function: KEDButton
        function KEDButtonPushed(app, event)
            %KED figure
            k=size(app.items);
            
            for i=1:k(2)
               
               load(['SavedResults/',app.items{i}]);
               
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               
               
               gui_plot_KED(h,name);
                
            end
        end

        % Button pushed function: MountForcesButton
        function MountForcesButtonPushed(app, event)
            
            k=size(app.items);
            for i=1:k(2)
              
               load(['SavedResults/',app.items{i}]);
               
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               gui_plot_force(h,name);
                
            end
        end

        % Button pushed function: MountLocationsButton
        function MountLocationsButtonPushed(app, event)
            
            figure(1);    hold on %figure of mount locations
            gui_plot_body;
            
            k=size(app.items);
            leg = "Car Body";    %plot legend
            for i=1:k(2)
                ['SavedResults/',app.items{i}]
               load(['SavedResults/',app.items{i}]);
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
            %                plot_legend=[plot_legend;app.items{i}(1:end-4)];
               leg = gui_plot_loc(h,name,leg);
                               
            end
            [q_TRA,~] = TRA_finder(h.eng.inertia,[0;1;0]); % finding TRA axis
            q_TRA = [0;0;0;q_TRA];
            %             TRA_plotter(q_TRA,h.sus.E_cm);
            E_cm = h.sus.E_cm
                 line([-600*q_TRA(4)+E_cm(1),600*q_TRA(4)+E_cm(1)],...
            [-600*q_TRA(5)+E_cm(2),600*q_TRA(5)+E_cm(2)],...
            [-600*q_TRA(6)+E_cm(3),600*q_TRA(6)+E_cm(3)],'Color','green')
            text(600*q_TRA(4)+E_cm(1),600*q_TRA(5)+E_cm(2),600*q_TRA(6)+E_cm(3),'TRA','color','k')
    
            leg = [leg;"TRA axis"];


            legend(leg);
        end

        % Button pushed function: MountStiffnessButton
        function MountStiffnessButtonPushed(app, event)
            
           
            k=size(app.items);
            for i=1:k(2)
               
               load(['SavedResults/',app.items{i}]);
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               ax.Name = [name,' >> Stiffness'];
               gui_plot_stiff(h,name);
               
            
            end
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            mat_files=dir('SavedResults/*_res.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.AvailableResultsListBox.Items(i)={mat_files(i).name};
                
            end
            app.AvailableResultsListBox.Value = app.AvailableResultsListBox.Items(1);
            
        end

        % Value changed function: SelectedResultsListBox
        function SelectedResultsListBoxValueChanged(app, event)
            
            app.items = app.SelectedResultsListBox.Items;
            
        end

        % Button pushed function: StaticDeflectionsButton
        function StaticDeflectionsButtonPushed(app, event)
            figure(5);    hold on; %static deflections figure
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
              
               load(app.items{i});
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               [K, ~] = stiff_cal(x_opt1_f,eta);
               stc_opt = abs((K/1.5)^(-1)*[0;0;M(1,1)*9.81;0;0;0]);
               st_def_plot(delta_s,stc_opt);
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectResultsTab = uitab(app.TabGroup);
            app.SelectResultsTab.Title = 'Select Results';
            app.AvailableResultsListBoxLabel = uilabel(app.SelectResultsTab);
            app.AvailableResultsListBoxLabel.Text = 'Available Results';
            app.AvailableResultsListBox = uilistbox(app.SelectResultsTab);
            app.AvailableResultsListBox.Items = {};
            app.AvailableResultsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableResultsListBoxValueChanged, true);
            app.AvailableResultsListBox.Value = {};
            app.AddResultButton = uibutton(app.SelectResultsTab, 'push');
            app.AddResultButton.ButtonPushedFcn = createCallbackFcn(app, @AddResultButtonPushed, true);
            app.AddResultButton.Text = 'Add Result';
            app.SelectedResultsListBoxLabel = uilabel(app.SelectResultsTab);
            app.SelectedResultsListBoxLabel.Text = 'Selected Results';
            app.SelectedResultsListBox = uilistbox(app.SelectResultsTab);
            app.SelectedResultsListBox.Items = {};
            app.SelectedResultsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedResultsListBoxValueChanged, true);
            app.SelectedResultsListBox.Value = {};
            app.RefreshButton = uibutton(app.SelectResultsTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DeleteButton = uibutton(app.SelectResultsTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.MountForcesButton = uibutton(app.SelectResultsTab, 'push');
            app.MountForcesButton.ButtonPushedFcn = createCallbackFcn(app, @MountForcesButtonPushed, true);
            app.MountForcesButton.Text = 'Mount Forces';
            app.CGDisplacementsButton = uibutton(app.SelectResultsTab, 'push');
            app.CGDisplacementsButton.Text = 'CG Displacements';
            app.StaticDeflectionsButton = uibutton(app.SelectResultsTab, 'push');
            app.StaticDeflectionsButton.ButtonPushedFcn = createCallbackFcn(app, @StaticDeflectionsButtonPushed, true);
            app.StaticDeflectionsButton.Text = 'Static Deflections';
            app.StaticTestsButton = uibutton(app.SelectResultsTab, 'push');
            app.StaticTestsButton.Text = 'Static Tests';
            app.MountLocationsTab = uitab(app.TabGroup);
            app.MountLocationsTab.Title = 'Mount Locations';
            app.MountLocationsButton = uibutton(app.MountLocationsTab, 'push');
            app.MountLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @MountLocationsButtonPushed, true);
            app.MountLocationsButton.Text = 'Mount Locations';
            app.MountStiffnessTab = uitab(app.TabGroup);
            app.MountStiffnessTab.Title = 'Mount Stiffness';
            app.MountStiffnessButton = uibutton(app.MountStiffnessTab, 'push');
            app.MountStiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @MountStiffnessButtonPushed, true);
            app.MountStiffnessButton.Text = 'Mount Stiffness';
            app.KEDTab = uitab(app.TabGroup);
            app.KEDTab.Title = 'KED';
            app.KEDButton = uibutton(app.KEDTab, 'push');
            app.KEDButton.ButtonPushedFcn = createCallbackFcn(app, @KEDButtonPushed, true);
            app.KEDButton.Text = 'KED';
            app.FrequencyTab = uitab(app.TabGroup);
            app.FrequencyTab.Title = 'Frequency';
            app.FrequencyButton = uibutton(app.FrequencyTab, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Text = 'Frequency';
            app.StaticDeflectionsTab = uitab(app.TabGroup);
            app.StaticDeflectionsTab.Title = 'Static Deflections';
            app.MountForcesTab = uitab(app.TabGroup);
            app.MountForcesTab.Title = 'Mount Forces';
            app.CGDisplacementsTab = uitab(app.TabGroup);
            app.CGDisplacementsTab.Title = 'CG Displacements';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_results

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end